classdef project_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LoadClassesButton             matlab.ui.control.Button
        DisplayImagesButton           matlab.ui.control.Button
        TestImageLabel                matlab.ui.control.Label
        PredictedImageLabel           matlab.ui.control.Label
        AppTitle                      matlab.ui.control.Label
        ClassesLoadedLabel            matlab.ui.control.Label
        NumClassesLoadedLabel         matlab.ui.control.Label
        ImagesLoadedLabel             matlab.ui.control.Label
        NumImagesLoadedLabel          matlab.ui.control.Label
        TrainingImagesLabel           matlab.ui.control.Label
        TestImagesLabel               matlab.ui.control.Label
        NumTrainingImagesLabel        matlab.ui.control.Label
        NumTestImagesLabel            matlab.ui.control.Label
        TrainingImagesPerClassLabel   matlab.ui.control.Label
        TrainingImagesPerClassSlider  matlab.ui.control.Slider
        NumImageSizeLabel             matlab.ui.control.Label
        ImageSizeLabel                matlab.ui.control.Label
        InputTestImageDisplay         matlab.ui.control.UIAxes
        PredictedImageDisplay         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        classnames % A 1D array of all the class names.
        classFiles % A matrix where the second dimension has 11 elements. Each entry has the class name first, then the 10 image file paths for that class.
        trainingimagepaths % Paths to the training images. Each row has the same shape and first classname entry as for the classFiles matrix.
        testimagepaths % Paths to the test images. Each row has the same shape and first classname entry as for the classFiles matrix.
        image_x % The length of each image, i.e. its horizontal size.
        image_y % The height of each image, i.e. its vertical size.
    end
    
    methods (Access = private)
        
        % If the classFiles object is not empty, get the dimensions of each
        % image. We assume that all the images we use have the same
        % dimensions.
        function success = get_image_size(app)
            num_images = 0;
            total_x = 0;
            total_y = 0;
            for i = 1:size(app.classFiles,1)
                for j = 2:size(app.classFiles,2)
                    current_img = imread(app.classFiles(i,j));
                    dimensions = size(current_img);
                    % Dimensions is a 2-tuple of the form (rows, columns).
                    total_x = total_x + dimensions(2);
                    total_y = total_y + dimensions(1);
                    num_images = num_images + 1;
                end
            end
            
            app.image_y = total_y ./ num_images;
            app.image_x = total_x ./ num_images;
            
            set(app.NumImageSizeLabel, 'Text', sprintf("%d x %d", app.image_x, app.image_y));
            
            success = true;
        end
        
        % If the classFiles object is not empty, split each class into
        % training and test images based on the value of "training_size".
        function success = train_test_split(app, training_size)
            if ~isempty(app.classFiles)
                
                % Initialize the training and test image matrices
                app.trainingimagepaths = strings([size(app.classFiles,1), training_size + 1]);
                app.testimagepaths = strings([size(app.classFiles,1), 10 - training_size + 1]);
                
                % For each class...
                for i = 1:size(app.classFiles,1)
                    % Assign the current class name.
                    app.trainingimagepaths(i,1) = app.classFiles(i,1);
                    app.testimagepaths(i,1) = app.classFiles(i,1);
                    
                        % Assign the training images to trainingimagepaths.
                        training_i = 2;
                        for j = 2:2+training_size - 1
                            app.trainingimagepaths(i,training_i) = app.classFiles(i,j);
                            training_i = training_i + 1;
                        end
                        
                        % Assign the test images to testimagepaths.
                        test_i = 2;
                        for j = 2+training_size:size(app.classFiles,2)
                            app.testimagepaths(i,test_i) = app.classFiles(i,j);
                            test_i = test_i + 1;
                        end
                        
                end
                
                % Update the relevant labels with the current sizes of each of the training and test image matrices.
                set(app.NumTrainingImagesLabel, 'Text', sprintf("%d",size(app.trainingimagepaths,1) * (size(app.trainingimagepaths,2) - 1)));
                set(app.NumTestImagesLabel, 'Text', sprintf("%d",size(app.testimagepaths,1) * (size(app.testimagepaths,2) - 1)))
                
                % Success!
                success = 1;
            else
                % Aww, that sucks.
                success = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadClassesButton
        function LoadClasses(app, event)
            % Get the path to the folder containing the face classes.
            face_path = uigetdir();
            fprintf(1, "User opened path: %s\n", face_path);
            face_folders = dir(face_path); % Get each unique class in the face folder.
            
            % Construct an array of class names, excluding the '.' and '..' entries.
            % We assume each class name CANNOT start with a fullstop, i.e. '.'
            classes = strings([length(face_folders) - 2,1]);
            classes_i = 1;
            for face_folders_i = 1:length(face_folders)
               if face_folders(face_folders_i).name(1) ~= '.'
                  classes(classes_i) = face_folders(face_folders_i).name;
                  classes_i = classes_i + 1;
               end
            end
            % Add the classes array to the global scope.
            app.classnames = classes;
            
            % Compile a list of class paths.
            classpaths = strings([length(classes), 1]);
            for i = 1:length(classpaths)
                % fullfile() builds the full file path from its arguments, OS specific.
                classpaths(i) = fullfile(face_path, classes(i));
            end
            
            % Compile a list of image paths for each class.
            % Each class is guaranteed to have exactly 10 images and each row in "class_filepaths" has 11 entries.
            % The first entry is the class name, followed by the paths for each of the 10 images.
            class_filepaths = strings([length(classpaths), 11]);
            % For each class path...
            for i = 1:length(classpaths)
                class_filepaths(i,1) = classes(i); % Assign the current class name.
                files = dir(classpaths(i)); % Get all the files, including '.' and '..'.
                j = 2;
                % Add each file if it has a ".pgm" extension.
                for files_i = 1:length(files)
                    [~,~,ext] = fileparts(files(files_i).name);
                    % The only extension allowed for image files is ".pgm".
                    if ext == ".pgm"
                        class_filepaths(i,j) = fullfile(classpaths(i), files(files_i).name);
                        j = j + 1;
                    end
                end
            end
            
            % Update the display text with the relevant information.
            set(app.NumClassesLoadedLabel, 'Text', sprintf("%d",length(app.classnames)));
            set(app.NumImagesLoadedLabel, 'Text', sprintf("%d",size(class_filepaths,1) * (size(class_filepaths,2) - 1)));
            % Add our class files to the global scope.
            app.classFiles = class_filepaths;
            
            % Add the average image size.
            get_image_size(app);
            
            % Split our data into training and test splits.
            training_size = floor(app.TrainingImagesPerClassSlider.Value);
            train_test_split(app, training_size);
            
        end

        % Button pushed function: DisplayImagesButton
        function DisplayImages(app, event)
            for i = 1:size(app.classFiles,1)
                for j = 2:size(app.classFiles,2)
                    current_img = imread(app.classFiles(i,j));
                    imshow(current_img, 'Parent', app.PredictedImageDisplay);
                    drawnow % Force the axes to update NOW!
                end
            end
        end

        % Value changing function: TrainingImagesPerClassSlider
        function NumTrainingImagesChanging(app, event)
            training_size = floor(event.Value);
            train_test_split(app, training_size);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LoadClassesButton = uibutton(app.GridLayout, 'push');
            app.LoadClassesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClasses, true);
            app.LoadClassesButton.Text = 'Load Classes';
            app.DisplayImagesButton = uibutton(app.GridLayout, 'push');
            app.DisplayImagesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayImages, true);
            app.DisplayImagesButton.Text = 'Display Images';
            app.TestImageLabel = uilabel(app.GridLayout);
            app.TestImageLabel.Text = 'Test Image';
            app.PredictedImageLabel = uilabel(app.GridLayout);
            app.PredictedImageLabel.Text = 'Predicted Image';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'LRC for Face Recognition & Classification';
            app.ClassesLoadedLabel = uilabel(app.GridLayout);
            app.ClassesLoadedLabel.Text = 'Classes Loaded:';
            app.NumClassesLoadedLabel = uilabel(app.GridLayout);
            app.NumClassesLoadedLabel.Text = '0';
            app.ImagesLoadedLabel = uilabel(app.GridLayout);
            app.ImagesLoadedLabel.Text = 'Images Loaded:';
            app.NumImagesLoadedLabel = uilabel(app.GridLayout);
            app.NumImagesLoadedLabel.Text = '0';
            app.TrainingImagesLabel = uilabel(app.GridLayout);
            app.TrainingImagesLabel.Text = 'Number of Training Images:';
            app.TestImagesLabel = uilabel(app.GridLayout);
            app.TestImagesLabel.Text = 'Number of Test Images:';
            app.NumTrainingImagesLabel = uilabel(app.GridLayout);
            app.NumTrainingImagesLabel.Text = '0';
            app.NumTestImagesLabel = uilabel(app.GridLayout);
            app.NumTestImagesLabel.Text = '0';
            app.TrainingImagesPerClassLabel = uilabel(app.GridLayout);
            app.TrainingImagesPerClassLabel.Text = 'Training Images Per Class';
            app.TrainingImagesPerClassSlider = uislider(app.GridLayout);
            app.TrainingImagesPerClassSlider.Limits = [1 10];
            app.TrainingImagesPerClassSlider.ValueChangingFcn = createCallbackFcn(app, @NumTrainingImagesChanging, true);
            app.TrainingImagesPerClassSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.TrainingImagesPerClassSlider.Value = 5;
            app.NumImageSizeLabel = uilabel(app.GridLayout);
            app.NumImageSizeLabel.Text = '0 x 0';
            app.ImageSizeLabel = uilabel(app.GridLayout);
            app.ImageSizeLabel.Text = 'Average Image Size:';
            app.InputTestImageDisplay = uiaxes(app.GridLayout);
            app.InputTestImageDisplay.XTick = [];
            app.InputTestImageDisplay.XTickLabel = '';
            app.InputTestImageDisplay.YTick = [];
            app.PredictedImageDisplay = uiaxes(app.GridLayout);
            app.PredictedImageDisplay.XTick = [];
            app.PredictedImageDisplay.XTickLabel = '';
            app.PredictedImageDisplay.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end